classdef KalkulatorKredytowy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        OkresEditFieldLabel           matlab.ui.control.Label
        OkresEditField                matlab.ui.control.NumericEditField
        KwotaEditFieldLabel           matlab.ui.control.Label
        KwotaEditField                matlab.ui.control.NumericEditField
        OprocentowanieEditFieldLabel  matlab.ui.control.Label
        OprocentowanieEditField       matlab.ui.control.NumericEditField
        RataEditFieldLabel            matlab.ui.control.Label
        RataEditField                 matlab.ui.control.NumericEditField
        ObliczButton                  matlab.ui.control.Button
        ProwizjaEditFieldLabel        matlab.ui.control.Label
        ProwizjaEditField             matlab.ui.control.NumericEditField
        CheckBox                      matlab.ui.control.CheckBox
        DropDown                      matlab.ui.control.DropDown
        UIAxes                        matlab.ui.control.UIAxes
        UITable                       matlab.ui.control.Table
        DropDown_2                    matlab.ui.control.DropDown
        ProwizjaLabel                 matlab.ui.control.Label
        EditField                     matlab.ui.control.NumericEditField
        EditField_2                   matlab.ui.control.NumericEditField
        EditField_4                   matlab.ui.control.NumericEditField
        KwotakredytowanaLabel         matlab.ui.control.Label
        SumaspatLabel                 matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        RatyDropDownLabel             matlab.ui.control.Label
        RatyDropDown                  matlab.ui.control.DropDown
        ZapiszdoplikuButton           matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: ObliczButton
        function ObliczButtonPushed(app, event)
            app.UITable.Data = {};
            kwota = app.KwotaEditField.Value;
            r = app.OprocentowanieEditField.Value / 100;
            n = app.OkresEditField.Value;
            m = 12; 
            q = 1 + r/m;
            
            prowizja = 0;
            odsetkiSuma = 0;
            
            if(app.CheckBox.Value == 1)
                if(app.DropDown_2.Value == "%")
                    prowizja = app.ProwizjaEditField.Value / 100 * kwota;
                else
                    prowizja = app.ProwizjaEditField.Value;
                end
                S = kwota + prowizja;
                S = ceil(S);
                kwota = S;
                disp(S);
            else
                S = kwota;
            end
            
            if(app.DropDown.Value == "lat")
                n = n*12;
            end
            
                        
            if(app.RatyDropDown.Value == "malejące")
               kapital = kwota / n;
               kwotaM = kwota;
               i = 1; 
                while i ~= n+1  
                   odsetki = kwotaM*r/m;
                   rata = kapital + odsetki;
                   odsetki = kwotaM*r/12;
                   format;
                   odsetki = ceil(odsetki*100)/100;
                   odsetkiSuma = odsetkiSuma + odsetki;
                   kapital = round(kapital, 2);
                   rata = round(rata, 2);
                   row = {i, odsetki, kapital, rata};
                   app.UITable.ColumnFormat = {'numeric', 'bank', 'bank', 'bank'};
                   app.UITable.Data = [app.UITable.Data; row];
                   kwotaM = kwotaM - kapital;
                   i = i+1;
                end
                
                sumaSplat = kwota + odsetkiSuma;
                rata = 0;
                
            else
                rata = S*q^n*((q-1)/(q^n-1));
                app.RataEditField.Value = rata;
                odsetkiSuma = rata*n - kwota;
                sumaSplat = rata*n;
            end
            
            
            fig = figure;
            Y = [odsetkiSuma kwota];
            labels = {'Odsetki', 'Kwota kredytu'};
            explode = [1 1];
            pie(Y, explode, labels);
            set(fig, 'Visible', 'off');
            saveas(fig,'diagram.png');
            
            imshow('diagram.png','Parent', app.UIAxes)
            
            app.EditField.Value = prowizja;
            app.EditField_2.Value = kwota;
            app.EditField_4.Value = sumaSplat;
            
            i = 1;
            
            if(app.RatyDropDown.Value == "równe")
                while i ~= n+1
                   odsetki = kwota*r/m;
                   kapital = rata - odsetki;
                   format;
                   odsetki = ceil(odsetki*100)/100;
                   kapital = round(kapital, 2);
                   rata = round(rata, 2);
                   row = {i, odsetki, kapital, rata};
                   app.UITable.ColumnFormat = {'numeric', 'bank', 'bank', 'bank'};
                   app.UITable.Data = [app.UITable.Data; row];
                   kwota = kwota - kapital;
                   i = i+1;
                end
            end
            
        end

        % Button pushed function: ZapiszdoplikuButton
        function ZapiszdoplikuButtonPushed(app, event)
            [filename, pathname] = uiputfile({'*.txt'; '*.dat'; '*.csv'; '*.xls'}, 'Zapisz plik jako');
            path_file = fullfile(pathname,filename);
            data = get(app.UITable, 'data');
            columns = get(app.UITable, 'columnname');
            columns = reshape(columns, [1,4]);
            tab = [columns; data];
            pat = [".xls", ".csv"]
            if(contains(path_file, pat) == 1)
                xlswrite(path_file, tab);
            else
                tab = array2table(data);
                tab.Properties.VariableNames = {'Nr' 'Odsetki' 'Kapital' 'Rata'};
                writetable(tab, path_file, 'Delimiter', '\t');
            end      
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.OkresEditFieldLabel = uilabel(app.UIFigure);
            app.OkresEditFieldLabel.Text = 'Okres';
            app.OkresEditField = uieditfield(app.UIFigure, 'numeric');
            app.KwotaEditFieldLabel = uilabel(app.UIFigure);
            app.KwotaEditFieldLabel.Text = 'Kwota';
            app.KwotaEditField = uieditfield(app.UIFigure, 'numeric');
            app.KwotaEditField.ValueDisplayFormat = '%.0f';
            app.OprocentowanieEditFieldLabel = uilabel(app.UIFigure);
            app.OprocentowanieEditFieldLabel.Text = 'Oprocentowanie';
            app.OprocentowanieEditField = uieditfield(app.UIFigure, 'numeric');
            app.OprocentowanieEditField.ValueDisplayFormat = '%5.2f';
            app.RataEditFieldLabel = uilabel(app.UIFigure);
            app.RataEditFieldLabel.Text = 'Rata';
            app.RataEditField = uieditfield(app.UIFigure, 'numeric');
            app.RataEditField.ValueDisplayFormat = '%5.2f';
            app.RataEditField.Editable = 'off';
            app.ObliczButton = uibutton(app.UIFigure, 'push');
            app.ObliczButton.ButtonPushedFcn = createCallbackFcn(app, @ObliczButtonPushed, true);
            app.ObliczButton.Text = 'Oblicz';
            app.ProwizjaEditFieldLabel = uilabel(app.UIFigure);
            app.ProwizjaEditFieldLabel.Text = 'Prowizja';
            app.ProwizjaEditField = uieditfield(app.UIFigure, 'numeric');
            app.ProwizjaEditField.ValueDisplayFormat = '%5.2f';
            app.CheckBox = uicheckbox(app.UIFigure);
            app.CheckBox.Text = '';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'miesięcy', 'lat'};
            app.DropDown.Value = 'miesięcy';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Wykres')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Nr raty'; 'Odsetki'; 'Kapitał'; 'Rata'};
            app.UITable.RowName = {};
            app.DropDown_2 = uidropdown(app.UIFigure);
            app.DropDown_2.Items = {'zł', '%'};
            app.DropDown_2.Value = 'zł';
            app.ProwizjaLabel = uilabel(app.UIFigure);
            app.ProwizjaLabel.Text = 'Prowizja';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueDisplayFormat = '%5.2f';
            app.EditField.Editable = 'off';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.ValueDisplayFormat = '%5.2f';
            app.EditField_2.Editable = 'off';
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4.ValueDisplayFormat = '%5.2f';
            app.EditField_4.Editable = 'off';
            app.KwotakredytowanaLabel = uilabel(app.UIFigure);
            app.KwotakredytowanaLabel.Text = 'Kwota kredytowana';
            app.SumaspatLabel = uilabel(app.UIFigure);
            app.SumaspatLabel.Text = 'Suma spłat';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '%';
            app.RatyDropDownLabel = uilabel(app.UIFigure);
            app.RatyDropDownLabel.Text = 'Raty';
            app.RatyDropDown = uidropdown(app.UIFigure);
            app.RatyDropDown.Items = {'równe', 'malejące'};
            app.RatyDropDown.Value = 'równe';
            app.ZapiszdoplikuButton = uibutton(app.UIFigure, 'push');
            app.ZapiszdoplikuButton.ButtonPushedFcn = createCallbackFcn(app, @ZapiszdoplikuButtonPushed, true);
            app.ZapiszdoplikuButton.Text = 'Zapisz do pliku';
        end
    end

    methods (Access = public)

        % Construct app
        function app = KalkulatorKredytowy

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end